classdef epm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        ElapsedTimeEditField       matlab.ui.control.EditField
        ElapsedTimeEditFieldLabel  matlab.ui.control.Label
        result_directory           matlab.ui.control.EditField
        animal_id                  matlab.ui.control.EditField
        Button_2                   matlab.ui.control.Button
        Button                     matlab.ui.control.Button
        CurrentRewardArmEditField  matlab.ui.control.EditField
        CurrentRewardArmLabel      matlab.ui.control.Label
        TrialEditField             matlab.ui.control.NumericEditField
        TrialEditFieldLabel        matlab.ui.control.Label
        CurrentStartArmEditField   matlab.ui.control.EditField
        CurrentStartArmLabel       matlab.ui.control.Label
        FirstRewardArmButtonGroup  matlab.ui.container.ButtonGroup
        R2Button                   matlab.ui.control.RadioButton
        R1Button                   matlab.ui.control.RadioButton
        table_id                   matlab.ui.control.Label
        text19_2                   matlab.ui.control.Label
        dir                        matlab.ui.control.Button
        text19                     matlab.ui.control.Label
        StartButton                matlab.ui.control.StateButton
    end

properties (Access = public)
    settings_path
    dirpath
    ps
    arduino
    animal_names
    sdata
    start_t % datetime format for csv filenames
    ack
    state
    timer
    stopped
    nano_list
    run
    state_timer_timeout
    state_timer
    resdir
    USBs
end

methods (Access = public)
    function change_cage_pub(app, event)
        value = app.CageDropDown.Value;
        value = str2num(erase(value,"#"));
        % update animal ID using array of names
        n = app.animal_names(value);
        app.animal_id.Value = n;
        if (app.USBs(value))
            app.statusLamp.Color = [0 1 0];
        else
            app.statusLamp.Color = [1 0 0];
        end
    end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
          addpath(genpath(['.' filesep 'src']));
          app.figure1.Name = 'Spatial Task GUI';
          app.result_directory.Value = pwd;
        end

        % Button pushed function: dir
        function dir_Callback(app, event)
            if isempty(app.dirpath)
                app.dirpath = pwd;
            end
            user_selection = uigetdir(app.dirpath);
            if user_selection == 0
                user_selection = pwd;
            end
            app.result_directory.Value = user_selection;
            app.dirpath = app.result_directory.Value;
            app.result_directory.Tooltip = user_selection;
            saveStatus(app,'gui_settings.mat');
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)

              saveStatus(app,'gui_settings.mat');
              delete(app);
              
        end

        % Callback function
        function SaveSettingsButtonPushed(app, event)
            
            if isempty(app.settings_path)
                app.settings_path = pwd;
            end
            [fn,pn,FilterIndex] = ...
                 uiputfile({'*.mat'},'Input data file',app.settings_path);
            if (pn ~= 0)
                app.settings_path = pn;
            else
                 return;
            end
            saveStatus(app,[pn filesep fn]);

        end

        % Callback function
        function LoadSettingsButtonPushed(app, event)
           [fn,pn,FilterIndex]  = ...
                 uigetfile({'*.mat'},'Input data file',app.settings_path);
            if (pn ~= 0)
                app.settings_path = pn;
            else
                 return;
            end
            loadStatus(app,[pn filesep fn]);
            
        end

        % Callback function
        function reward_topButtonPushed(app, event)
           vl = app.reward_duration_top.Value;
           write(app.arduino,['r' 0],"uint8");
           fprintf('%4.3f  %s\n',toc,'Top Track Reward Given Manually');
        end

        % Callback function
        function dir2_Callback(app, event)
             if isempty(app.dirpath)
                app.dirpath2 = pwd;
            end
            user_selection = uigetdir(app.dirpath);
            if user_selection == 0
                user_selection = pwd;
            end
            app.result_directory2.Value = user_selection;
            app.dirpath2 = app.result_directory2.Value;
            app.result_directory2.Tooltip = user_selection;
        end

        % Callback function
        function motion_toggle(app, event)
            if app.stopped == false
                value = app.MotionDetectorSwitch_2.Value;
                if value == "On"
                    app.Lamp1.Color = [0, 1, 0];
                    % tells arduino to resume top state machine updates
                    write(app.arduino,['a' 1 1],"uint8");
                else
                    app.Lamp1.Color = [1, 0, 0];
                    % tells arduino to pause top state machine
                    write(app.arduino,['a' 1 0],"uint8");
                end
            else
                app.MotionDetectorSwitch_2.Value = 'Off';
            end
        end

        % Callback function
        function dbl_click_nano_list(app, event)
            app.table_id.Text = "CONNECTING...";
            item = event.InteractionInformation.Item;
            n = app.nano_list.Items{item};
            comstr = ['COM' num2str(n)];

            app.arduino = [];
            % create serial communication object on port COM
            app.arduino=serialport(comstr,115200);
            app.arduino.Timeout = 0.05;

            % start serial reads in background
            configureTerminator(app.arduino,"CR/LF");
            configureCallback(app.arduino,"terminator",@(src,evnt)read_serial(src,evnt,app))
        end

        % Callback function
        function start_btn(app, event)
            function localTimerFx(~,~)
                elapsed_sec = toc;
                mins = floor(elapsed_sec/60);
                secs = floor(mod(elapsed_sec, 60));
                app.ElapsedTimeEditField_2.Value = mins + "m " + secs + "s";
            end

            if app.stopped == true
                app.start_t = "_" + string(datetime);
                app.stopped = false;
                app.start.Text = "Stop";
                app.timer = timer;
                app.timer.ExecutionMode = 'fixedRate';
                app.timer.TimerFcn = @localTimerFx;
                app.timer.Period = 1; % runs every 1 seconds
                start(app.timer);
                app.MotionDetectorSwitch_2.Value = 'On';
                app.PORTEditField.Value = "0";
                app.Lamp1.Color = [0, 1, 0];
                % tell the arduino to unlock top state machine
                write(app.arduino,['a' 1 1],"uint8");
            else
                app.stopped = true;
                app.start.Text = "Start";
                app.MotionDetectorSwitch_2.Value = 'Off';
                app.Lamp1.Color = [1, 0, 0];
                stop(app.timer)
                app.ElapsedTimeEditField_2.Value = '0m 0s';
                % tell the arduino to lock top state machine
                write(app.arduino,['a' 1 0],"uint8");
            end
        end

        % Callback function
        function motion_toggle_bot(app, event)
            % ignore button press if not recording yet
            if app.stopped2 == false
                value = app.MotionDetectorSwitch.Value;
                if value == "On"
                    app.Lamp2.Color = [0, 1, 0];
                    % tells arduino to resume top state machine updates
                    write(app.arduino,['a' 0 1],"uint8");
                else
                    app.Lamp2.Color = [1, 0, 0];
                    % tells arduino to pause top state machine
                    write(app.arduino,['a' 0 0],"uint8");
                end
            else
                app.MotionDetectorSwitch.Value = 'Off';
            end
        end

        % Callback function
        function r_dt_change(app, event)
            v = app.reward_duration_top.Value;
            % tell nano the new top solenoid open duration
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 0 lo hi],"uint8");
        end

        % Callback function
        function r_dt_change_bot(app, event)
            v = app.reward_duration_bottom.Value;
            % tell nano the new top solenoid open duration
            lo =  mod(v,256); 
            hi =  floor(v/256); 
            write(app.arduino,['t' 1 lo hi],"uint8");
        end

        % Callback function
        function change_cage(app, event)
            change_cage_pub(app, event);
        end

        % Value changing function: animal_id
        function update_animal_names(app, event)
            changingValue = event.Value;
            i = app.CageDropDown.Value;
            i = str2num(erase(i,"#"));
            app.animal_names(i) = changingValue;
            save_RAD(app,'RAD_settings.mat');
        end

        % Callback function
        function dose_given(app, event)
            p1 = app.result_directory.Value;
            i = app.CageDropDown.Value;
            i = str2num(erase(i,"#"));
            % update animal ID using array of names
            p2 = app.animal_names(i);
            a_dir = fullfile(p1, p2);
            if ~exist(a_dir, 'dir')
               mkdir(a_dir);
            end
            csv_path = fullfile(a_dir, "doses.csv");
            t = string(datetime());
            writematrix(t,csv_path,'WriteMode','append');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'main';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.StartButton = uibutton(app.figure1, 'state');
            app.StartButton.Text = 'Start';
            app.text19 = uilabel(app.figure1);
            app.text19.Text = 'Animal ID';
            app.dir = uibutton(app.figure1, 'push');
            app.dir.ButtonPushedFcn = createCallbackFcn(app, @dir_Callback, true);
            app.dir.Text = '📁';
            app.text19_2 = uilabel(app.figure1);
            app.text19_2.Text = 'Result Dir';
            app.table_id = uilabel(app.figure1);
            app.table_id.Text = 'Spatial Task GUI';
            app.FirstRewardArmButtonGroup = uibuttongroup(app.figure1);
            app.FirstRewardArmButtonGroup.Tooltip = {'What reward did the mouse choose on the first trial?'};
            app.FirstRewardArmButtonGroup.Title = 'First Reward Arm';
            app.R1Button = uiradiobutton(app.FirstRewardArmButtonGroup);
            app.R1Button.Text = 'R1';
            app.R1Button.Value = true;
            app.R2Button = uiradiobutton(app.FirstRewardArmButtonGroup);
            app.R2Button.Text = 'R2';
            app.CurrentStartArmLabel = uilabel(app.figure1);
            app.CurrentStartArmLabel.Text = {'Current '; 'Start Arm'};
            app.CurrentStartArmEditField = uieditfield(app.figure1, 'text');
            app.CurrentStartArmEditField.Value = 'S1';
            app.TrialEditFieldLabel = uilabel(app.figure1);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.figure1, 'numeric');
            app.CurrentRewardArmLabel = uilabel(app.figure1);
            app.CurrentRewardArmLabel.Text = {'Current'; 'Reward Arm'};
            app.CurrentRewardArmEditField = uieditfield(app.figure1, 'text');
            app.CurrentRewardArmEditField.Value = 'BOTH';
            app.Button = uibutton(app.figure1, 'push');
            app.Button.Tooltip = {'CORRECT CHOICE'};
            app.Button.Text = '✔️';
            app.Button_2 = uibutton(app.figure1, 'push');
            app.Button_2.Tooltip = {'WRONG CHOICE'};
            app.Button_2.Text = '❌';
            app.animal_id = uieditfield(app.figure1, 'text');
            app.animal_id.ValueChangingFcn = createCallbackFcn(app, @update_animal_names, true);
            app.animal_id.Placeholder = 'turtle';
            app.result_directory = uieditfield(app.figure1, 'text');
            app.result_directory.Editable = 'off';
            app.ElapsedTimeEditFieldLabel = uilabel(app.figure1);
            app.ElapsedTimeEditFieldLabel.Text = 'Elapsed Time';
            app.ElapsedTimeEditField = uieditfield(app.figure1, 'text');
            app.ElapsedTimeEditField.Value = '00m 00s';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = epm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end